classdef PlantChiliDiseaseDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlantChiliDiseaseDetectionUIFigure  matlab.ui.Figure
        LoadCropImageButton         matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        EnhanceConstratButton       matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
        SegmentImageButton          matlab.ui.control.Button
        UIAxes3                     matlab.ui.control.UIAxes
        ClassificationResultButton  matlab.ui.control.Button
        TextArea                    matlab.ui.control.TextArea
        FeaturesOfSegmentedImageButtonGroup  matlab.ui.container.ButtonGroup
        MeanEditFieldLabel          matlab.ui.control.Label
        MeanEditField               matlab.ui.control.NumericEditField
        SDEditFieldLabel            matlab.ui.control.Label
        SDEditField                 matlab.ui.control.NumericEditField
        EntropyEditFieldLabel       matlab.ui.control.Label
        EntropyEditField            matlab.ui.control.NumericEditField
        RMSEditFieldLabel           matlab.ui.control.Label
        RMSEditField                matlab.ui.control.NumericEditField
        IDMEditFieldLabel           matlab.ui.control.Label
        IDMEditField                matlab.ui.control.NumericEditField
        HomogeneityEditFieldLabel   matlab.ui.control.Label
        HomogeneityEditField        matlab.ui.control.NumericEditField
        VarianceEditFieldLabel      matlab.ui.control.Label
        VarianceEditField           matlab.ui.control.NumericEditField
        EnergyEditFieldLabel        matlab.ui.control.Label
        EnergyEditField             matlab.ui.control.NumericEditField
        CorrelationEditFieldLabel   matlab.ui.control.Label
        CorrelationEditField        matlab.ui.control.NumericEditField
        ContrastEditFieldLabel      matlab.ui.control.Label
        ContrastEditField           matlab.ui.control.NumericEditField
        SmoothnessEditFieldLabel    matlab.ui.control.Label
        SmoothnessEditField         matlab.ui.control.NumericEditField
        SkewnessEditFieldLabel      matlab.ui.control.Label
        SkewnessEditField           matlab.ui.control.NumericEditField
        KurtosisEditFieldLabel      matlab.ui.control.Label
        KurtosisEditField           matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        I;
        seg_img;
        Mean,Contrast,Correlation,Energy,Homogeneity, Standard_Deviation, Entropy, RMS, Variance, Smoothness, Kurtosis, Skewness, IDM;
    end


    methods (Access = private)

        % Button pushed function: ClassificationResultButton
        function ClassificationResultButtonPushed(app, event)
            
            
            feat_disease = [app.Contrast,app.Correlation,app.Energy,app.Homogeneity, app.Mean, app.Standard_Deviation, app.Entropy, app.RMS, app.Variance,app.Smoothness, app.Kurtosis, app.Skewness, app.IDM];
            %%
            % Load All The Features
            load('Healthy.mat')
            load('Bacterial_Leaf_Spot.mat')
            load('Moises.mat')
            
            T = [Healthy;Bacterial_Leaf_Spot;Moises];
            C = [ zeros(1,10) ones(1,10) 2*ones(1,10) ];
            % Put the test features into variable 'test'
            test = feat_disease;
            result = multisvm(T,C,test);


            if result == 0
                app.TextArea = uitextarea(app.PlantChiliDiseaseDetectionUIFigure,'Value','Healthy');
                app.TextArea.FontSize = 16;
                app.TextArea.Position = [254.67188 15 160 49];
            elseif result == 1
                app.TextArea = uitextarea(app.PlantChiliDiseaseDetectionUIFigure,'Value','Bacterial Leaf Spot');
                app.TextArea.FontSize = 16;
                app.TextArea.Position = [254.67188 15 160 49];
            elseif result == 2
                app.TextArea = uitextarea(app.PlantChiliDiseaseDetectionUIFigure,'Value','Moises');
                app.TextArea.FontSize = 16;
                app.TextArea.Position = [254.67188 15 160 49];
            end
        end

        % Button pushed function: EnhanceConstratButton
        function EnhanceConstratButtonPushed(app, event)
            app.I = imadjust(app.I,stretchlim(app.I));
            imshow(app.I,'parent',app.UIAxes2);
        end

        % Button pushed function: LoadCropImageButton
        function LoadCropImageButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.*';'*.bmp';'*.jpg';'*.gif'}, 'Pick a Leaf Image File');
            I = imread([pathname,filename]);
            app.I=imcrop(I);
            app.I = imresize(app.I,[256,256]);
            imshow(app.I,'parent',app.UIAxes);
            
        end

        % Button pushed function: SegmentImageButton
        function SegmentImageButtonPushed(app, event)
            lab_he = rgb2lab(app.I);
            
            ab = double(lab_he(:,:,2:3));
            nrows = size(ab,1);
            ncols = size(ab,2);
            ab = reshape(ab,nrows*ncols,2);
            nColors = 3;
            [cluster_idx cluster_center] = kmeans(ab,nColors,'distance','sqEuclidean', ...
                'Replicates',3);
            
            pixel_labels = reshape(cluster_idx,nrows,ncols);
            
            
            % Create a blank cell array to store the results of clustering
            segmented_images = cell(1,3);
            % Create RGB label using pixel_labels
            rgb_label = repmat(pixel_labels,[1,1,3]);
            
            for k = 1:nColors
                colors = app.I;
                colors(rgb_label ~= k) = 0;
                segmented_images{k} = colors;
            end
            
            figure,
            subplot(3,1,1);imshow(segmented_images{1});title('Cluster 1');
            subplot(3,1,2);imshow(segmented_images{2});title('Cluster 2');
            subplot(3,1,3);imshow(segmented_images{3});title('Cluster 3');
            
            x = inputdlg('Enter the cluster no. containing the ROI only:');
            i = str2double(x);
            
            app.seg_img = segmented_images{i};
            imshow(app.seg_img,'parent',app.UIAxes3);
         
            
            % Convert to grayscale if image is RGB
            if ndims(app.seg_img) == 3
                img = rgb2gray(app.seg_img);
            end
            
            
            % Create the Gray Level Cooccurance Matrices
            glcms = graycomatrix(img);
            
            % Derive Statistics from GLCM
            stats = graycoprops(glcms,'Contrast Correlation Energy Homogeneity');
            app.Contrast = stats.Contrast;
            app.Correlation = stats.Correlation;
           app. Energy = stats.Energy;
            app.Homogeneity = stats.Homogeneity;
            app.Mean = mean2(app.seg_img);
            app.Standard_Deviation = std2(app.seg_img);
            app.Entropy = entropy(app.seg_img);
            app.RMS = mean2(rms(app.seg_img));
            app.Variance = mean2(var(double(app.seg_img)));
            a = sum(double(app.seg_img(:)));
            app.Smoothness = 1-(1/(1+a));
            app.Kurtosis = kurtosis(double(app.seg_img(:)));
            app.Skewness = skewness(double(app.seg_img(:)));
            % Inverse Difference Movement
            m = size(app.seg_img,1);
            n = size(app.seg_img,2);
            in_diff = 0;
            for i = 1:m
                for j = 1:n
                    temp = app.seg_img(i,j)./(1+(i-j).^2);
                    in_diff = in_diff+temp;
                end
            end
            app.IDM = double(in_diff);
            
           
            app.MeanEditField = uieditfield(app.FeaturesOfSegmentedImageButtonGroup,'numeric','Value',app.Mean);
            app.MeanEditField.Position = [100 211 100 22];
            
            app.SDEditField = uieditfield(app.FeaturesOfSegmentedImageButtonGroup, 'numeric','Value',app.Standard_Deviation);
            app.SDEditField.Position = [100 177 100 22];
            
            app.EntropyEditField = uieditfield(app.FeaturesOfSegmentedImageButtonGroup, 'numeric','Value',app.Entropy);
            app.EntropyEditField.Position = [100 144 100 22];
            
            app.RMSEditField = uieditfield(app.FeaturesOfSegmentedImageButtonGroup, 'numeric','Value',app.RMS);
            app.RMSEditField.Position = [100 114 100 22];
            
            app.IDMEditField = uieditfield(app.FeaturesOfSegmentedImageButtonGroup, 'numeric','Value',app.IDM);
            app.IDMEditField.Position = [100 83 100 22];
            
            app.HomogeneityEditField = uieditfield(app.FeaturesOfSegmentedImageButtonGroup, 'numeric','Value',app.Homogeneity);
            app.HomogeneityEditField.Position = [100 48 100 22];
            
            app.VarianceEditField = uieditfield(app.FeaturesOfSegmentedImageButtonGroup, 'numeric','Value', app.Variance);
            app.VarianceEditField.Position = [333 207 100 22];
            
            app.EnergyEditField = uieditfield(app.FeaturesOfSegmentedImageButtonGroup, 'numeric','Value',app.Energy);
            app.EnergyEditField.Position = [331 172 100 22];
            
            app.CorrelationEditField = uieditfield(app.FeaturesOfSegmentedImageButtonGroup, 'numeric','Value',app.Correlation);
            app.CorrelationEditField.Position = [331 139 100 22];
            
            app.ContrastEditField = uieditfield(app.FeaturesOfSegmentedImageButtonGroup, 'numeric','Value',app.Contrast);
            app.ContrastEditField.Position = [331 109 100 22];
            
            app.SmoothnessEditField = uieditfield(app.FeaturesOfSegmentedImageButtonGroup, 'numeric','Value',app.Smoothness);
            app.SmoothnessEditField.Position = [330.109375 78 100 22];
            
            app.SkewnessEditField = uieditfield(app.FeaturesOfSegmentedImageButtonGroup, 'numeric','Value',app.Skewness);
            app.SkewnessEditField.Position = [331 43 100 22];
            
            app.KurtosisEditField = uieditfield(app.FeaturesOfSegmentedImageButtonGroup, 'numeric','Value',app.Kurtosis);
            app.KurtosisEditField.Position = [201 6 100 22];


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlantChiliDiseaseDetectionUIFigure = uifigure;
            app.PlantChiliDiseaseDetectionUIFigure.Name = 'Plant Chili Disease Detection';
            app.LoadCropImageButton = uibutton(app.PlantChiliDiseaseDetectionUIFigure, 'push');
            app.LoadCropImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCropImageButtonPushed, true);
            app.LoadCropImageButton.Text = 'Load & Crop Image';
            app.UIAxes = uiaxes(app.PlantChiliDiseaseDetectionUIFigure);
            title(app.UIAxes, 'Input Image')
            app.EnhanceConstratButton = uibutton(app.PlantChiliDiseaseDetectionUIFigure, 'push');
            app.EnhanceConstratButton.ButtonPushedFcn = createCallbackFcn(app, @EnhanceConstratButtonPushed, true);
            app.EnhanceConstratButton.Text = 'Enhance Constrat';
            app.UIAxes2 = uiaxes(app.PlantChiliDiseaseDetectionUIFigure);
            title(app.UIAxes2, 'Enhanced Image')
            app.SegmentImageButton = uibutton(app.PlantChiliDiseaseDetectionUIFigure, 'push');
            app.SegmentImageButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentImageButtonPushed, true);
            app.SegmentImageButton.Text = 'Segment Image';
            app.UIAxes3 = uiaxes(app.PlantChiliDiseaseDetectionUIFigure);
            title(app.UIAxes3, 'Segmented Image')
            app.ClassificationResultButton = uibutton(app.PlantChiliDiseaseDetectionUIFigure, 'push');
            app.ClassificationResultButton.ButtonPushedFcn = createCallbackFcn(app, @ClassificationResultButtonPushed, true);
            app.ClassificationResultButton.Text = 'Classification Result';
            app.TextArea = uitextarea(app.PlantChiliDiseaseDetectionUIFigure);
            app.FeaturesOfSegmentedImageButtonGroup = uibuttongroup(app.PlantChiliDiseaseDetectionUIFigure);
            app.FeaturesOfSegmentedImageButtonGroup.Title = 'Features Of Segmented Image';
            app.MeanEditFieldLabel = uilabel(app.FeaturesOfSegmentedImageButtonGroup);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanEditField = uieditfield(app.FeaturesOfSegmentedImageButtonGroup, 'numeric');
            app.SDEditFieldLabel = uilabel(app.FeaturesOfSegmentedImageButtonGroup);
            app.SDEditFieldLabel.Text = 'S.D';
            app.SDEditField = uieditfield(app.FeaturesOfSegmentedImageButtonGroup, 'numeric');
            app.EntropyEditFieldLabel = uilabel(app.FeaturesOfSegmentedImageButtonGroup);
            app.EntropyEditFieldLabel.Text = 'Entropy';
            app.EntropyEditField = uieditfield(app.FeaturesOfSegmentedImageButtonGroup, 'numeric');
            app.RMSEditFieldLabel = uilabel(app.FeaturesOfSegmentedImageButtonGroup);
            app.RMSEditFieldLabel.Text = 'RMS';
            app.RMSEditField = uieditfield(app.FeaturesOfSegmentedImageButtonGroup, 'numeric');
            app.IDMEditFieldLabel = uilabel(app.FeaturesOfSegmentedImageButtonGroup);
            app.IDMEditFieldLabel.Text = 'IDM';
            app.IDMEditField = uieditfield(app.FeaturesOfSegmentedImageButtonGroup, 'numeric');
            app.HomogeneityEditFieldLabel = uilabel(app.FeaturesOfSegmentedImageButtonGroup);
            app.HomogeneityEditFieldLabel.Text = 'Homogeneity';
            app.HomogeneityEditField = uieditfield(app.FeaturesOfSegmentedImageButtonGroup, 'numeric');
            app.VarianceEditFieldLabel = uilabel(app.FeaturesOfSegmentedImageButtonGroup);
            app.VarianceEditFieldLabel.Text = 'Variance';
            app.VarianceEditField = uieditfield(app.FeaturesOfSegmentedImageButtonGroup, 'numeric');
            app.EnergyEditFieldLabel = uilabel(app.FeaturesOfSegmentedImageButtonGroup);
            app.EnergyEditFieldLabel.Text = 'Energy';
            app.EnergyEditField = uieditfield(app.FeaturesOfSegmentedImageButtonGroup, 'numeric');
            app.CorrelationEditFieldLabel = uilabel(app.FeaturesOfSegmentedImageButtonGroup);
            app.CorrelationEditFieldLabel.Text = 'Correlation';
            app.CorrelationEditField = uieditfield(app.FeaturesOfSegmentedImageButtonGroup, 'numeric');
            app.ContrastEditFieldLabel = uilabel(app.FeaturesOfSegmentedImageButtonGroup);
            app.ContrastEditFieldLabel.Text = 'Contrast';
            app.ContrastEditField = uieditfield(app.FeaturesOfSegmentedImageButtonGroup, 'numeric');
            app.SmoothnessEditFieldLabel = uilabel(app.FeaturesOfSegmentedImageButtonGroup);
            app.SmoothnessEditFieldLabel.Text = 'Smoothness';
            app.SmoothnessEditField = uieditfield(app.FeaturesOfSegmentedImageButtonGroup, 'numeric');
            app.SkewnessEditFieldLabel = uilabel(app.FeaturesOfSegmentedImageButtonGroup);
            app.SkewnessEditFieldLabel.Text = 'Skewness';
            app.SkewnessEditField = uieditfield(app.FeaturesOfSegmentedImageButtonGroup, 'numeric');
            app.KurtosisEditFieldLabel = uilabel(app.FeaturesOfSegmentedImageButtonGroup);
            app.KurtosisEditFieldLabel.Text = 'Kurtosis';
            app.KurtosisEditField = uieditfield(app.FeaturesOfSegmentedImageButtonGroup, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = PlantChiliDiseaseDetection

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlantChiliDiseaseDetectionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlantChiliDiseaseDetectionUIFigure)
        end
    end
end